classdef watermarkApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure   matlab.ui.Figure
        Button13   matlab.ui.control.Button
        NCLabel    matlab.ui.control.Label
        PSNRLabel  matlab.ui.control.Label
        Label2     matlab.ui.control.Label
        Label1     matlab.ui.control.Label
        Button10   matlab.ui.control.Button
        Button12   matlab.ui.control.Button
        Button11   matlab.ui.control.Button
        Button9    matlab.ui.control.Button
        Button8    matlab.ui.control.Button
        Button6    matlab.ui.control.Button
        Button7    matlab.ui.control.Button
        Button5    matlab.ui.control.Button
        Button4    matlab.ui.control.Button
        Button3    matlab.ui.control.Button
        Button2    matlab.ui.control.Button
        Button1    matlab.ui.control.Button
        UIAxes6    matlab.ui.control.UIAxes
        UIAxes5    matlab.ui.control.UIAxes
        UIAxes4    matlab.ui.control.UIAxes
        UIAxes3    matlab.ui.control.UIAxes
        UIAxes2    matlab.ui.control.UIAxes
        UIAxes1    matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        key = 'qwertyuiopasdfghjklzxcvbnm123456'
    end

    methods (Access = public)

        function result = encrypt(~, img, key)
            if length(size(img)) ~= 2
                error('图像格式错误');
            end
            key_array = uint8(key);
            if length(key_array) ~= 32
                error('密钥格式错误');
            end
            s = sum(key_array);
            x0 = s / (128 * length(key_array));
            u = 3.99;
            % fprintf('初始值x0=%f\n', x0);
            % fprintf('参数u=%f\n', u);
            [h, w] = size(img);
            c = zeros(1, h * w);
            c(1) = x0;
            for i = 2:h*w
                c(i) = u * c(i - 1) * (1 - c(i - 1));
            end
            c1 = uint8(c * 255);
            M1 = reshape(c1, h, w);
            result1 = bitxor(img, M1);
            s = RandStream('mt19937ar', 'Seed', x0, 'NormalTransform', 'Polar');
            c2 = randperm(s, h * w);
            c3 = zeros(1, h * w);
            for i = 1:h * w
                if c3(i) == 1
                    continue;
                end
                if c3(c2(i)) == 1
                    continue;
                end
                temp = result1(i);
                result1(i) = result1(c2(i));
                result1(c2(i)) = temp;
                c3(i) = 1;
                c3(c2(i)) = 1;
            end
            result = result1;
        end

        function result = decrypt(~, img, key)
            if length(size(img)) ~= 2
                error('图像格式错误');
            end
            key_array = uint8(key);
            if length(key_array) ~= 32
                error('密钥格式错误');
            end
            s = sum(key_array);
            x0 = s / (128 * length(key_array));
            u = 3.99;
            % fprintf('初始值x0=%f\n', x0);
            % fprintf('参数u=%f\n', u);
            [h, w] = size(img);
            c = zeros(1, h * w);
            c(1) = x0;
            for i = 2:h*w
                c(i) = u * c(i - 1) * (1 - c(i - 1));
            end
            c1 = uint8(c * 255);
            M1 = reshape(c1, h, w);
            result1 = img;
            s = RandStream('mt19937ar', 'Seed', x0, 'NormalTransform', 'Polar');
            c2 = randperm(s, h * w);
            c3 = zeros(1, h * w);
            for i = 1:h * w
                if c3(i) == 1
                    continue;
                end
                if c3(c2(i)) == 1
                    continue;
                end
                temp = result1(i);
                result1(i) = result1(c2(i));
                result1(c2(i)) = temp;
                c3(i) = 1;
                c3(c2(i)) = 1;
            end
            result = bitxor(result1, M1);
        end

        function result = embedded_watermark(app, img, w, key)
            img_enc = app.encrypt(img, key);
            w = w / 255;
            % 图像的高度和宽度
            [height, width] = size(img_enc);
            [w_height, w_width] = size(w);
            % 随机数流
            s = RandStream('mt19937ar', 'Seed', sum(uint8(key)), 'NormalTransform', 'Polar');
            % 位置矩阵
            pos = randperm(s, height * width, w_height * w_width);
            % 嵌入
            for i = 1:w_height*w_width
                v = img_enc(pos(i));
                ss = RandStream('mt19937ar', 'Seed', pos(i), 'NormalTransform', 'Polar');
                p = round(4 + (6 - 4) * rand(ss));
                v1 = bitget(v, p + 1);
                v2 = bitxor(v1, w(i));
                img_enc(pos(i)) = bitset(v, p, v2);
            end
            % 解密
            result = app.decrypt(img_enc, key);
        end
        
        function result = extract_watermark(app, img, key)
            w = zeros(64, 64);
            % 图像的高度和宽度
            [height, width] = size(img);
            [w_height, w_width] = size(w);
            % 随机数流
            s = RandStream('mt19937ar', 'Seed', sum(uint8(key)), 'NormalTransform', 'Polar');
            % 位置矩阵
            pos = randperm(s, height * width, w_height * w_width);
            img_enc = app.encrypt(img, key);
            for i = 1:w_height*w_width
                v = img_enc(pos(i));
                ss = RandStream('mt19937ar', 'Seed', pos(i), 'NormalTransform', 'Polar');
                p = round(4 + (6 - 4) * rand(ss));
                v1 = bitget(v, p + 1);
                v2 = bitget(v, p);
                w(i) = bitxor(v1, v2);
            end
            result = w * 255;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp'; '*.*'}, '选择图像');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('没有选择文件', '错误');
                return;
            end
            path = strcat(pathname, filename);
            I = imread(path);
            app.UIAxes1.UserData = I;
            imshow(I, 'Parent', app.UIAxes1);
            title(app.UIAxes1, '原始图像');
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            [filename, pathname] = uigetfile({'*.bmp'; '*.*'}, '选择图像');
            if isequal(filename, 0) || isequal(pathname, 0)
                errordlg('没有选择文件', '错误');
                return;
            end
            path = strcat(pathname, filename);
            w = imread(path);
            app.UIAxes2.UserData = w;
            imshow(w, 'Parent', app.UIAxes2);
            title(app.UIAxes2, '水印图像');
        end

        % Button pushed function: Button3
        function Button3Pushed(app, event)
            I = app.UIAxes1.UserData;
            w = app.UIAxes2.UserData;
            I1 = app.embedded_watermark(I, w, app.key);
            app.UIAxes3.UserData = I1;
            imshow(I1, 'Parent', app.UIAxes3);
            title(app.UIAxes3, '嵌入水印后的图像');
            v = psnr(I, I1);
            app.PSNRLabel.Text = sprintf('PSNR: %f', v);
        end

        % Button pushed function: Button4
        function Button4Pushed(app, event)
            I = app.UIAxes3.UserData;
            w1 = app.extract_watermark(I, app.key);
            app.UIAxes4.UserData = w1;
            imshow(w1, 'Parent', app.UIAxes4);
            title(app.UIAxes4, '提取的水印图像');
            w = app.UIAxes2.UserData;
            v = nc(w, w1);
            app.NCLabel.Text = sprintf('NC: %f', v);
        end

        % Button pushed function: Button5
        function Button5Pushed(app, event)
            I = app.UIAxes3.UserData;
            I1 = imnoise(I, 'gaussian', 0, 0.0001);
            app.UIAxes5.UserData = I1;
            imshow(I1, 'Parent', app.UIAxes5);
            title(app.UIAxes4, '高斯噪声');
            v = psnr(I, I1);
            app.PSNRLabel.Text = sprintf('PSNR: %f', v);
        end

        % Button pushed function: Button13
        function Button13Pushed(app, event)
            I = app.UIAxes5.UserData;
            w1 = app.extract_watermark(I, app.key);
            app.UIAxes6.UserData = w1;
            imshow(w1, 'Parent', app.UIAxes6);
            title(app.UIAxes6, '提取的水印');
            w = app.UIAxes2.UserData;
            v = nc(w, w1);
            app.NCLabel.Text = sprintf('NC: %f', v);
        end

        % Button pushed function: Button6
        function Button6Pushed(app, event)
            I = app.UIAxes3.UserData;
            I1 = imnoise(I, 'salt & pepper');
            app.UIAxes5.UserData = I1;
            imshow(I1, 'Parent', app.UIAxes5);
            title(app.UIAxes5, '椒盐噪声');
            v = psnr(I, I1);
            app.PSNRLabel.Text = sprintf('PSNR: %f', v);
        end

        % Button pushed function: Button7
        function Button7Pushed(app, event)
            I = app.UIAxes3.UserData;
            I1 = imnoise(I, 'speckle', 0.001);
            app.UIAxes5.UserData = I1;
            imshow(I1, 'Parent', app.UIAxes5);
            title(app.UIAxes5, '乘性噪声');
            v = psnr(I, I1);
            app.PSNRLabel.Text = sprintf('PSNR: %f', v);
        end

        % Button pushed function: Button8
        function Button8Pushed(app, event)
            I = app.UIAxes3.UserData;
            I1 = I;
            I1(1:128, 1:128) = 0;
            app.UIAxes5.UserData = I1;
            imshow(I1, 'Parent', app.UIAxes5);
            title(app.UIAxes5, '左上角1/4');
            v = psnr(I, I1);
            app.PSNRLabel.Text = sprintf('PSNR: %f', v);
        end

        % Button pushed function: Button9
        function Button9Pushed(app, event)
            I = app.UIAxes3.UserData;
            I1 = I;
            I1(1:128, 384:512) = 0;
            app.UIAxes5.UserData = I1;
            imshow(I1, 'Parent', app.UIAxes5);
            title(app.UIAxes5, '左上角1/4');
            v = psnr(I, I1);
            app.PSNRLabel.Text = sprintf('PSNR: %f', v);
        end

        % Button pushed function: Button10
        function Button10Pushed(app, event)
            I = app.UIAxes3.UserData;
            I1 = I;
            I1(192:320, 192:320) = 0;
            app.UIAxes5.UserData = I1;
            imshow(I1, 'Parent', app.UIAxes5);
            title(app.UIAxes5, '左上角1/4');
            v = psnr(I, I1);
            app.PSNRLabel.Text = sprintf('PSNR: %f', v);
        end

        % Button pushed function: Button11
        function Button11Pushed(app, event)
            I = app.UIAxes3.UserData;
            I1 = I;
            I1(384:512, 1:128) = 0;
            app.UIAxes5.UserData = I1;
            imshow(I1, 'Parent', app.UIAxes5);
            title(app.UIAxes5, '左上角1/4');
            v = psnr(I, I1);
            app.PSNRLabel.Text = sprintf('PSNR: %f', v);
        end

        % Button pushed function: Button12
        function Button12Pushed(app, event)
            I = app.UIAxes3.UserData;
            I1 = I;
            I1(384:512, 384:512) = 0;
            app.UIAxes5.UserData = I1;
            imshow(I1, 'Parent', app.UIAxes5);
            title(app.UIAxes5, '左上角1/4');
            v = psnr(I, I1);
            app.PSNRLabel.Text = sprintf('PSNR: %f', v);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes1 = uiaxes(app.UIFigure);
            app.UIAxes1.XTick = [];
            app.UIAxes1.YTick = [];
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.XTick = [];
            app.UIAxes2.YTick = [];
            app.UIAxes3 = uiaxes(app.UIFigure);
            app.UIAxes3.XTick = [];
            app.UIAxes3.YTick = [];
            app.UIAxes4 = uiaxes(app.UIFigure);
            app.UIAxes4.XTick = [];
            app.UIAxes4.YTick = [];
            app.UIAxes5 = uiaxes(app.UIFigure);
            app.UIAxes5.XTick = [];
            app.UIAxes5.YTick = [];
            app.UIAxes6 = uiaxes(app.UIFigure);
            app.UIAxes6.XTick = [];
            app.UIAxes6.YTick = [];
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = '加载原始图像';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Text = '加载水印图像';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @Button3Pushed, true);
            app.Button3.Text = '嵌入水印';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @Button4Pushed, true);
            app.Button4.Text = '提取水印';
            app.Button5 = uibutton(app.UIFigure, 'push');
            app.Button5.ButtonPushedFcn = createCallbackFcn(app, @Button5Pushed, true);
            app.Button5.Text = '高斯噪声';
            app.Button7 = uibutton(app.UIFigure, 'push');
            app.Button7.ButtonPushedFcn = createCallbackFcn(app, @Button7Pushed, true);
            app.Button7.Text = '乘性噪声';
            app.Button6 = uibutton(app.UIFigure, 'push');
            app.Button6.ButtonPushedFcn = createCallbackFcn(app, @Button6Pushed, true);
            app.Button6.Text = '椒盐噪声';
            app.Button8 = uibutton(app.UIFigure, 'push');
            app.Button8.ButtonPushedFcn = createCallbackFcn(app, @Button8Pushed, true);
            app.Button8.Text = '左上角1/4';
            app.Button9 = uibutton(app.UIFigure, 'push');
            app.Button9.ButtonPushedFcn = createCallbackFcn(app, @Button9Pushed, true);
            app.Button9.Text = '右上角1/4';
            app.Button11 = uibutton(app.UIFigure, 'push');
            app.Button11.ButtonPushedFcn = createCallbackFcn(app, @Button11Pushed, true);
            app.Button11.Text = '左下角1/4';
            app.Button12 = uibutton(app.UIFigure, 'push');
            app.Button12.ButtonPushedFcn = createCallbackFcn(app, @Button12Pushed, true);
            app.Button12.Text = '右下角1/4';
            app.Button10 = uibutton(app.UIFigure, 'push');
            app.Button10.ButtonPushedFcn = createCallbackFcn(app, @Button10Pushed, true);
            app.Button10.Text = '中心1/4';
            app.Label1 = uilabel(app.UIFigure);
            app.Label1.Text = '噪声攻击';
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = '剪切攻击';
            app.PSNRLabel = uilabel(app.UIFigure);
            app.PSNRLabel.Text = 'PSNR: ';
            app.NCLabel = uilabel(app.UIFigure);
            app.NCLabel.Text = 'NC:  ';
            app.Button13 = uibutton(app.UIFigure, 'push');
            app.Button13.ButtonPushedFcn = createCallbackFcn(app, @Button13Pushed, true);
            app.Button13.Text = '提取攻击后的水印';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = watermarkApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end